classdef SistemaEstacionamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SISTEMADEESTACIONAMIENTOLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ManualButton                   matlab.ui.control.Button
        ComenzarButton                 matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            max = 5;
            autos = zeros(1,max); %Arreglo para los autos del estacionamiento de dimension 1x5.
            tope = 1; topeCola = 1; %contadores
            aux = [];
            dato = ""; %Variable de tipo entero
            % MENU CONTEXTUAL
%             msg = 'Bienvenido(a) a la recepción del estacionamiento. ¿Que actividad desea hacer?';
%             titulo = 'MENÚ';
%             selec = uiconfirm(app.UIFigure,msg,titulo, ...
%                 'Options',{'Ingresar Vehículo','Mostrar Vehículos Ingresados','Salida de Vehículos'}, ...
%                 'DefaultOption',1);

            msg = "Bienvenido(a) a la recepción del estacionamiento. ¿Que actividad desea hacer?'";
            opts = ["Ingresar Vehículo" "Mostrar Vehículos Ingresados" "Salida de Vehículos" "Cerrar"];
            choice = menu(msg,opts);

             
%             opts.Interpreter = 'tex';
%             % Include the desired Default answer
%             opts.Default = '';
%             % Use the TeX interpreter to format the question
%             cadena = 'Bienvenido(a) a la recepción del estacionamiento. ¿Que actividad desea hacer?';
%             quest = cadena;
%             opcion1 = 'Ingresar Vehiculo';
%             opcion2 = 'Mostrar Vehiculos Ingresados';
%             opcion3 = 'Salida de Vehiculo'
% 
%             opc = {opcion1,opcion2,opcion3};
%            
%             answer = questdlg(quest,'Menu:',opcion1,opcion2,opcion3,opts);
           
%             if strcmp(selec,'Ingresar Vehículo')
%                 disp('OK')
%             elseif strcmp(selec,'Mostrar Vehículos Ingresados')
%                 disp('OK 2')
%             elseif strcmp(selec,'Salida de Vehículos')
%                 disp('OK 3')
%             else
%                 clc
%             end
% 
% 
      
              if choice == 1
                 disp('OK')
                 answer = inputdlg('Por favor ingrese la placa del vehiculo:',...
                     'Ingreso de Vehiculos', [1 35])
                 dato = string(answer{1})
                 disp(dato);
                 aux = string(autos)
                 aux(tope) = string(answer{1});
                 tope = tope+1;
                 disp(aux)

                 message = {'Vehiculo ingresado exitosamente.'};
                 uialert(app.UIFigure,message,'Ingreso de Vehiculos',...
                     'Icon','info');
                 

              elseif choice == 2
                 disp('OK 2')
                  app.TextArea.Value="";
                  app.TextArea.Value=string(autos);


              elseif choice == 3
                  disp('OK 3');
                  answer = inputdlg('Por favor ingrese la placa del vehiculo que desea salir del estacionamiento:',...
                     'Salida de Vehiculos', [1 35])
                 dato = string(answer{1})
                  for tope=1:5
                      if dato == autos(tope)
                          autos(tope) = [];
                      end
                 end
                  

              elseif choice == 4
                  disp('OK 4')
              else
                  clc
              end


        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
        manual = ['Manual de Ayuda' newline 'Para comenzar a estacionar o sacar autos presione el botón Comenzar' newline '- Para Salir del programa completo puede hacer click en la X roja' newline "Uso del Programa:" newline "- Al presionar el botón Comenzar se ejecutara el cuerpo del programa donde usted introducira la opción de su preferecia ya sea  opcion1 para 'Ingresar Vehiculo'; opcion2 para 'Mostrar Vehiculos'; opcion3 para 'Salida de Vehiculo' y opcion4 para 'Salir' respectivamente" newline "- Si da click en la opcion 1 el programa le pedira el número de la placa del auto que desea introducir" newline "- Si da click en la opcion 2 el programa le pedira el número de la placa del auto que desea retirar." newline "- Si da click en la opcion 3 el programa le mostrara el estado del estacionamiento en tiempo real." newline "- Si da click en la opcion 4 el programa cerrara la ejecución del cuerpo del programa y podrá presionar el mismo u otro botón si desea." newline "Nota: Si elige la opción 4 sin haber terminado de introducir todos los dato\n, perdera los datos que haya introducido previamente."];

        app.TextArea.Value=manual;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'images.jpg';
            app.ComenzarButton = uibutton(app.UIFigure, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.SISTEMADEESTACIONAMIENTOLabel = uilabel(app.UIFigure);
            app.SISTEMADEESTACIONAMIENTOLabel.Text = 'SISTEMA DE ESTACIONAMIENTO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemaEstacionamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end